classdef CommunicationWithMATLAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CommunicationLabUIFigure  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        SendServoValueButton      matlab.ui.control.Button
        ServoValueEditField       matlab.ui.control.NumericEditField
        ServoValueEditFieldLabel  matlab.ui.control.Label
        ServoSlider               matlab.ui.control.Slider
        ServoSliderLabel          matlab.ui.control.Label
        CountLabel                matlab.ui.control.Label
        ButtoncounterLabel        matlab.ui.control.Label
        LEDButtonGroup            matlab.ui.container.ButtonGroup
        ONButton                  matlab.ui.control.ToggleButton
        OFFButton                 matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        counter = 0;
        serialObj % Description
    end
    
    methods (Access = private)
        function sendServoValue(app, degrees)
            % degrees is a number (maybe an int, maybe a double)
            degrees = round(degrees);
            command = sprintf("SERVO %d", degrees);
            
            fprintf("Sending %s\n", command);
            writeline(app.serialObj, command);
        end
        
        function serialObjCallback(app, serialObj, eventData)
            message = strtrim(readline(serialObj))
            
            % No Paarameters
            if strcmpi(message, "B")
                app.counter = app.counter + 1;
                app.CountLabel.Text = sprintf("Count = %d", app.counter);
            end
            
            % Parameters
            messageArray = split(message);
            if strcmpi(messageArray(1), "X")
                xValue = str2double(messageArray(2));
                app.Image.Position(1) = xValue/2;
            end
            if strcmpi(messageArray(1), "Y")
                yValue = str2double(messageArray(2));
                app.Image.Position(2) = yValue/2;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.serialObj = serialport("COM7", 9600);
            flush(app.serialObj);
            configureCallback(app.serialObj, "terminator", @app.serialObjCallback)
        end

        % Selection changed function: LEDButtonGroup
        function LEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.LEDButtonGroup.SelectedObject;
            if selectedButton == app.OFFButton
                writeline(app.serialObj, "LED OFF")
            end
            if selectedButton == app.ONButton
                writeline(app.serialObj, "LED ON")
            end
        end

        % Value changed function: ServoSlider
        function ServoSliderValueChanged(app, event)
            value = app.ServoSlider.Value; 
            app.sendServoValue(value);
        end

        % Value changing function: ServoSlider
        function ServoSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sendServoValue(changingValue);
        end

        % Button pushed function: SendServoValueButton
        function SendServoValueButtonPushed(app, event)
            value = app.ServoValueEditField.Value;
            app.sendServoValue(value);
            app.ServoSlider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CommunicationLabUIFigure = uifigure('Visible', 'off');
            app.CommunicationLabUIFigure.Name = 'Communication Lab';
            app.LEDButtonGroup = uibuttongroup(app.CommunicationLabUIFigure);
            app.LEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LEDButtonGroupSelectionChanged, true);
            app.LEDButtonGroup.Title = 'LED';
            app.OFFButton = uitogglebutton(app.LEDButtonGroup);
            app.OFFButton.Text = 'OFF';
            app.OFFButton.Value = true;
            app.ONButton = uitogglebutton(app.LEDButtonGroup);
            app.ONButton.Text = 'ON';
            app.ButtoncounterLabel = uilabel(app.CommunicationLabUIFigure);
            app.ButtoncounterLabel.Text = 'Button counter:';
            app.CountLabel = uilabel(app.CommunicationLabUIFigure);
            app.CountLabel.Text = 'Count = 0';
            app.ServoSliderLabel = uilabel(app.CommunicationLabUIFigure);
            app.ServoSliderLabel.Text = {'Servo'; 'Slider'};
            app.ServoSlider = uislider(app.CommunicationLabUIFigure);
            app.ServoSlider.Limits = [0 180];
            app.ServoSlider.MajorTicks = [0 20 40 60 80 100 120 140 160 180];
            app.ServoSlider.ValueChangedFcn = createCallbackFcn(app, @ServoSliderValueChanged, true);
            app.ServoSlider.ValueChangingFcn = createCallbackFcn(app, @ServoSliderValueChanging, true);
            app.ServoValueEditFieldLabel = uilabel(app.CommunicationLabUIFigure);
            app.ServoValueEditFieldLabel.Text = {'Servo'; 'Value'};
            app.ServoValueEditField = uieditfield(app.CommunicationLabUIFigure, 'numeric');
            app.SendServoValueButton = uibutton(app.CommunicationLabUIFigure, 'push');
            app.SendServoValueButton.ButtonPushedFcn = createCallbackFcn(app, @SendServoValueButtonPushed, true);
            app.SendServoValueButton.Text = 'Send Servo Value';
            app.Image = uiimage(app.CommunicationLabUIFigure);
            app.Image.ImageSource = 'ship.png';
            % Show the figure after all components are created
            app.CommunicationLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CommunicationWithMATLAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CommunicationLabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CommunicationLabUIFigure)
        end
    end
end