classdef HelloButton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HelloButtonUIFigure  matlab.ui.Figure
        CountLabel           matlab.ui.control.Label
        IncrementButton      matlab.ui.control.Button
        DecrementButton      matlab.ui.control.Button
        ResetButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        counter = 0; % Counter for buttons
        serialObj;
    end
    
    methods (Access = private)
        
        function updateView(app)
            fprintf("Count = %d\n", app.counter);
            app.CountLabel.Text = sprintf("Count = %d", app.counter);
        end
        
        function serialObjCallback(app, serialObj, eventData)
            %           fprintf("Hit the callback!\n");
            message = strtrim(readline(serialObj));
            if strcmpi(message, "B")
                app.counter = app.counter + 1;
                app.updateView();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % This functionruns when the app starts
            clc
            app.serialObj = serialport("COM7", 9600);
            app.counter = 0;
            flush(app.serialObj);
            configureCallback(app.serialObj, "terminator", @app.serialObjCallback);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %            fprintf("You pressed the button\n");
            %            app.ResetButton.BackgroundColor = "y";
            %            app.HelloButtonUIFigure.Color = "r";
            %            app.ResetButton.Text = "Jordan Asman";
            % update counter
            app.counter = 0;
            % update display
            app.updateView;
        end

        % Button pushed function: DecrementButton
        function DecrementButtonPushed(app, event)
            % update counter
            app.counter = app.counter - 1;
            % update display
            app.updateView;
            writeline(app.serialObj, "LED OFF");
        end

        % Button pushed function: IncrementButton
        function IncrementButtonPushed(app, event)
            % update counter
            app.counter = app.counter + 1;
            % update display
            app.updateView;
            writeline(app.serialObj, "LED ON");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HelloButtonUIFigure = uifigure('Visible', 'off');
            app.HelloButtonUIFigure.Name = 'Hello Button';
            app.ResetButton = uibutton(app.HelloButtonUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DecrementButton = uibutton(app.HelloButtonUIFigure, 'push');
            app.DecrementButton.ButtonPushedFcn = createCallbackFcn(app, @DecrementButtonPushed, true);
            app.DecrementButton.Text = 'Decrement';
            app.IncrementButton = uibutton(app.HelloButtonUIFigure, 'push');
            app.IncrementButton.ButtonPushedFcn = createCallbackFcn(app, @IncrementButtonPushed, true);
            app.IncrementButton.Text = 'Increment';
            app.CountLabel = uilabel(app.HelloButtonUIFigure);
            app.CountLabel.Text = 'Count = 0';
            % Show the figure after all components are created
            app.HelloButtonUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HelloButton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HelloButtonUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HelloButtonUIFigure)
        end
    end
end